classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Switch                      matlab.ui.control.Switch
        StartFreqMHzEditField       matlab.ui.control.NumericEditField
        StartFreqMHzEditFieldLabel  matlab.ui.control.Label
        StopFreqMHzEditField        matlab.ui.control.NumericEditField
        StopFreqMHzEditFieldLabel   matlab.ui.control.Label
        BWHzEditField               matlab.ui.control.NumericEditField
        BWHzEditFieldLabel          matlab.ui.control.Label
        RFEyeStationDropDown        matlab.ui.control.DropDown
        RFEyeStationDropDownLabel   matlab.ui.control.Label
        Lamp                        matlab.ui.control.Lamp
        UIAxes1                     matlab.ui.control.UIAxes
        UIAxes2                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Flag; % Se on: plot; se off: para o plot
        sweep_cap; % Description
    end
    
    methods (Access = private)
        
        function Plot(app)
            
            % Pre-allocation:
            [~, startFreqMHz, stopFreqMHz, numSamples, ~] = step(app.sweep_cap);
            
            freqs = linspace(startFreqMHz, stopFreqMHz, numSamples);
            sweepData  = zeros(numSamples, 1, 'single');
            sortedData = zeros(numSamples, 1, 'single');
            
            % StepWidth
            StepWidth = round(1e+3*(stopFreqMHz - startFreqMHz)/(numSamples-1), 1); % in kHz
           
            % Plot
            app.UIAxes1.YLim  = [-120, -20];
            app.UIAxes1.YTick = fix(linspace(-120, -20, 5));
            
            xlabel(app.UIAxes1, 'Frequency (MHz)')
            
            n = 1;
            OccData = zeros(numSamples,10);
            occTot  = zeros(numSamples, 1);
            thresh  = zeros(1, 1, 'single');
            
            CountTraces = 0;
            
            % Spectral data
            while app.Flag
                sweepData(:,1) = step(app.sweep_cap);
                sortedData(:) = sort(sweepData);
                
                auxthresh = mean(sortedData(1:round(0.2*length(sweepData)))) + 12;
                if thresh ~= auxthresh
                    thresh(:) = auxthresh;
                    CountTraces = CountTraces+1;
                else
%                     app.Switch.Value = 'Off';
%                     SwitchValueChanged(app);
%                     break
                    disp(sprintf('Número de amostras da conexão: %d', CountTraces));
                    CountTraces = 0;
                    reset(app.sweep_cap);
                    continue
                end
                
                OccData(:,n) = sweepData > thresh;
                
                % Plot 1: Spectral data and threshold
                cla(app.UIAxes1)
                line(app.UIAxes1, freqs, sweepData);
                hold on
                line(app.UIAxes1, [freqs(1) freqs(end)], [thresh thresh], 'Color', 'red');
                hold off
                
                n = n + 1;
                if n == 10
                    occTot(:) = sum(OccData,2)/n;
                    
                    % plot of occTot X freqs
                    cla(app.UIAxes2)
                    bar(app.UIAxes2, freqs, occTot);
                    n = 1;            
                end
                drawnow nocallbacks

            end
            
            % Disconnect from node and unload library
            reset(app.sweep_cap);
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.Flag = false;
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            
            value = app.Switch.Value;
            
            switch value
                case 'On'
                    StartFreq = app.StartFreqMHzEditField.Value;
                    StopFreq = app.StopFreqMHzEditField.Value;
                    bw = app.BWHzEditField.Value;
                    station = app.RFEyeStationDropDown.Value;
                    
                    app.Lamp.Color = 'green';
                    
                    % Number of captures
                    numCapture = 1000;
                    
                    % Load ncp_sweep_data object
                    app.sweep_cap = ncp_sweep_data;
                    
                    % Parameters:
                    app.sweep_cap.startFreqMHzReq = StartFreq;          % Start Frequency (MHz)
                    app.sweep_cap.stopFreqMHzReq = StopFreq;          % Stop Frequency (MHz)
                    app.sweep_cap.bandwidthHz = bw;           % Bandwidth (Hz)
                    switch station
                        case 'Paripe'
                            app.sweep_cap.node_ip = '189.89.182.38';    % IP Address
                        case 'Campo Grande'
                            app.sweep_cap.node_ip = '189.89.182.42';    % IP Address
                        case 'Villa Privilege'
                            app.sweep_cap.node_ip = '187.44.203.199';    % IP Address
                    end
                    
                    app.sweep_cap.node_port = 9999;              % Port
                    
                    app.Flag = true;
                    Plot(app)
            
                case 'Off'
                    app.Flag = false;
                    app.Lamp.Color = 'red';
            end


            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.StartFreqMHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartFreqMHzEditField.Value = 88;
            app.StartFreqMHzEditFieldLabel = uilabel(app.UIFigure);
            app.StartFreqMHzEditFieldLabel.Text = 'Start Freq (MHz)';
            app.StopFreqMHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.StopFreqMHzEditField.Value = 108;
            app.StopFreqMHzEditFieldLabel = uilabel(app.UIFigure);
            app.StopFreqMHzEditFieldLabel.Text = 'Stop Freq (MHz)';
            app.BWHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.BWHzEditField.Value = 200000;
            app.BWHzEditFieldLabel = uilabel(app.UIFigure);
            app.BWHzEditFieldLabel.Text = 'BW (Hz)';
            app.RFEyeStationDropDown = uidropdown(app.UIFigure);
            app.RFEyeStationDropDown.Items = {'Paripe', 'Campo Grande', 'Villa Privilege'};
            app.RFEyeStationDropDown.Value = 'Paripe';
            app.RFEyeStationDropDownLabel = uilabel(app.UIFigure);
            app.RFEyeStationDropDownLabel.Text = 'RFEye Station';
            app.Lamp = uilamp(app.UIFigure);
            app.UIAxes1 = uiaxes(app.UIFigure);
            ylabel(app.UIAxes1, 'Nível (dBm)')
            zlabel(app.UIAxes1, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'Frequência (Hz)')
            ylabel(app.UIAxes2, 'Ocupação (%)')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end